classdef DoAnMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        NghiemTab                   matlab.ui.container.Tab
        Nghiem_DoThi                matlab.ui.control.UIAxes
        NhpphngtrnhEditFieldLabel   matlab.ui.control.Label
        Nghiem_NhapPhuongTrinh      matlab.ui.control.EditField
        KhongphnlyabEditFieldLabel  matlab.ui.control.Label
        Nghiem_KhoangPhanLy_a       matlab.ui.control.NumericEditField
        Nghiem_KhoangPhanLy_b       matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel    matlab.ui.control.Label
        Nghiem_SaiSoChoPhep         matlab.ui.control.NumericEditField
        PhngphptmDropDownLabel      matlab.ui.control.Label
        Nghiem_PhuongPhap           matlab.ui.control.DropDown
        KtqunghimTextAreaLabel      matlab.ui.control.Label
        Nghiem_KetQua               matlab.ui.control.TextArea
        SlnlpTextAreaLabel          matlab.ui.control.Label
        Nghiem_SoLanLap             matlab.ui.control.TextArea
        NhpfpEditFieldLabel         matlab.ui.control.Label
        Nghiem_Nhapfp               matlab.ui.control.EditField
        Nghiem_Nut                  matlab.ui.control.Button
        NoiSuyTab                   matlab.ui.container.Tab
        NoiSuy_DoThi                matlab.ui.control.UIAxes
        PhngphpnisuyLabel           matlab.ui.control.Label
        NoiSuy_PhuongPhap           matlab.ui.control.DropDown
        GitrnisuyLabel              matlab.ui.control.Label
        NoiSuy_GiaTriNoiSuy         matlab.ui.control.NumericEditField
        PhngtrnhLabel               matlab.ui.control.Label
        NoiSuy_DaThuc               matlab.ui.control.TextArea
        KtqunisuyTextAreaLabel      matlab.ui.control.Label
        NoiSuy_KetQua               matlab.ui.control.TextArea
        NhpdliuxyEditFieldLabel_3   matlab.ui.control.Label
        NoiSuy_x                    matlab.ui.control.EditField
        NoiSuy_y                    matlab.ui.control.EditField
        NoiSuy_Nut                  matlab.ui.control.Button
        HoiQuyTab                   matlab.ui.container.Tab
        HoiQuy_DoThi                matlab.ui.control.UIAxes
        GitrcnsuyonLabel            matlab.ui.control.Label
        HoiQuy_GiaTriSuyDoan        matlab.ui.control.NumericEditField
        PhngphphiquyLabel           matlab.ui.control.Label
        HoiQuy_PhuongPhap           matlab.ui.control.DropDown
        PhngtrnhLabel_2             matlab.ui.control.Label
        HoiQuy_PhuongTrinh          matlab.ui.control.TextArea
        KtqudonLabel                matlab.ui.control.Label
        HoiQuy_KetQua               matlab.ui.control.TextArea
        NhpxyEditFieldLabel         matlab.ui.control.Label
        HoiQuy_x                    matlab.ui.control.EditField
        Label_11                    matlab.ui.control.Label
        HoiQuy_y                    matlab.ui.control.EditField
        HoiQuy_Nut                  matlab.ui.control.Button
        HoiQuy_Label                matlab.ui.control.Label
        HoiQuy_TanSo                matlab.ui.control.NumericEditField
        DaoHamTab                   matlab.ui.container.Tab
        NhphmsLabel                 matlab.ui.control.Label
        DaoHam_HamSo                matlab.ui.control.EditField
        GitrcnohmLabel              matlab.ui.control.Label
        DaoHam_GiaTri               matlab.ui.control.NumericEditField
        GitrsaisSwitchLabel         matlab.ui.control.Label
        DaoHam_GiaTriSaiSo          matlab.ui.control.Switch
        PhngphpohmLabel             matlab.ui.control.Label
        DaoHam_PhuongPhap           matlab.ui.control.DropDown
        KtquTextAreaLabel           matlab.ui.control.Label
        DaoHam_KetQua               matlab.ui.control.TextArea
        KtquchnhxcLabel             matlab.ui.control.Label
        DaoHam_KetQuaChinhXac       matlab.ui.control.TextArea
        NhpdliuxyEditFieldLabel     matlab.ui.control.Label
        DaoHam_x                    matlab.ui.control.EditField
        DaoHam_y                    matlab.ui.control.EditField
        NoiSuy_Nut_2                matlab.ui.control.Button
        TichPhanTab                 matlab.ui.container.Tab
        NhpNLabel                   matlab.ui.control.Label
        TichPhan_N                  matlab.ui.control.TextArea
        PhngphptchphnLabel          matlab.ui.control.Label
        TichPhan_PhuongPhap         matlab.ui.control.DropDown
        KtquTextAreaLabel_2         matlab.ui.control.Label
        TichPhan_KetQua             matlab.ui.control.TextArea
        NhpdliuxyEditFieldLabel_2   matlab.ui.control.Label
        TichPhan_x                  matlab.ui.control.EditField
        TichPhan_y                  matlab.ui.control.EditField
        NhpcnabLabel                matlab.ui.control.Label
        TichPhan_a                  matlab.ui.control.EditField
        TichPhan_b                  matlab.ui.control.EditField
        NhphmsLabel_2               matlab.ui.control.Label
        TichPhan_HamSo              matlab.ui.control.EditField
        TichPhan_NutNhan            matlab.ui.control.Button
        GioiThieuTab                matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        Label_5                     matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        Label_6                     matlab.ui.control.Label
        EditField_6                 matlab.ui.control.EditField
        Label_7                     matlab.ui.control.Label
        EditField_7                 matlab.ui.control.EditField
        Label_8                     matlab.ui.control.Label
        EditField_8                 matlab.ui.control.EditField
        Label_9                     matlab.ui.control.Label
        EditField_9                 matlab.ui.control.EditField
        Label_10                    matlab.ui.control.Label
        EditField_10                matlab.ui.control.EditField
        CngvicButton                matlab.ui.control.Button
        CngvicButton_2              matlab.ui.control.Button
        CngvicButton_3              matlab.ui.control.Button
        CngvicButton_4              matlab.ui.control.Button
        CngvicButton_5              matlab.ui.control.Button
        CngvicButton_6              matlab.ui.control.Button
        CngvicButton_7              matlab.ui.control.Button
    end

    
    properties (Access = private)
    end


    methods (Access = private)

        % Button pushed function: NoiSuy_Nut_2
        function DaoHam_GiaTriValueChanged(app, event)
        addpath(fullfile(pwd,'DoAnDaoHam')); %Duong dan file ham chuc nang

        DaoHam_x_val = str2num(app.DaoHam_x.Value);
        DaoHam_y_val = str2num(app.DaoHam_y.Value);
        
        DaoHam_diemdaoham = app.DaoHam_GiaTri.Value;
        DaoHam_phuongphap = app.DaoHam_PhuongPhap.Value;
        DaoHam_saiso = app.DaoHam_GiaTriSaiSo.Value;
        DaoHam_vitri = find(DaoHam_x_val == DaoHam_diemdaoham);
        DaoHam_hamso = app.DaoHam_HamSo.Value;
        DaoHam_fx = str2func(['@(x) ' DaoHam_hamso]);
        DaoHam_ketqua_val = DaoHam_fx(DaoHam_diemdaoham);
        DaoHam_i = DaoHam_vitri;
        DaoHam_h = DaoHam_x_val(DaoHam_i+1)-DaoHam_x_val(DaoHam_i);
        app.DaoHam_KetQuaChinhXac.Value = num2str(DaoHam_ketqua_val);
        disp(DaoHam_i)
        switch DaoHam_phuongphap
            case 'Xấp xỉ tiến'
                app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
                DaoHam_kq = xapxitien(DaoHam_y_val,DaoHam_i,DaoHam_saiso,DaoHam_h);
            case 'Xấp xỉ lùi'
                app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
                DaoHam_kq = xapxilui(DaoHam_y_val,DaoHam_i,DaoHam_saiso,DaoHam_h);
            case 'Xấp xỉ trung tâm'
                %Doi sai so cat cut O(h)->O(h^2), O(h^2)->O(h^4)
                app.DaoHam_GiaTriSaiSo.Items = {'O(h^2)', 'O(h^4)'};            
                DaoHam_kq = xapxitrungtam(DaoHam_y_val,DaoHam_i,DaoHam_saiso,DaoHam_h);
        end
                app.DaoHam_KetQua.Value = num2str(DaoHam_kq);
        end

        % Button pushed function: TichPhan_NutNhan
        function TichPhan_NValueChanged2(app, event)
        addpath(fullfile(pwd,'DoAnTichPhan')); %Duong dan file ham chuc nang
        
        TichPhan_a_val = str2double(app.TichPhan_a.Value);
        TichPhan_b_val = str2double(app.TichPhan_b.Value);        
        TichPhan_N_val = str2double(app.TichPhan_N.Value); 
        
        if ~isnan(app.TichPhan_x.Value) 
            TichPhan_x_val = cellfun(@eval, strsplit(app.TichPhan_x.Value));
        else
            TichPhan_x_val = NaN;
        end
        if ~isnan(app.TichPhan_y.Value) 
            TichPhan_y_val = cellfun(@eval, strsplit(app.TichPhan_y.Value));
        else
            TichPhan_y_val = NaN;
        end
        TichPhan_PhuongPhap_val = app.TichPhan_PhuongPhap.Value;
        TichPhan_HamSo_val = app.TichPhan_HamSo.Value;
        if ~isnan(TichPhan_HamSo_val)
            TichPhan_fx = str2func(['@(x) ' TichPhan_HamSo_val]);
        else
            TichPhan_fx = NaN;
        end
        switch TichPhan_PhuongPhap_val
        case 'Hình thang'
            TichPhan_kq = TichPhanHinhThang(TichPhan_fx, TichPhan_a_val, ...
            TichPhan_b_val, TichPhan_N_val, TichPhan_x_val, TichPhan_y_val);
        case 'Simpson 1/3'
            TichPhan_kq = TichPhanSimpson1_3(TichPhan_fx, TichPhan_a_val, ...
            TichPhan_b_val, TichPhan_N_val, TichPhan_x_val, TichPhan_y_val);
        case 'Simpson 3/8'
            TichPhan_kq = TichPhanSimpson3_8(TichPhan_fx, TichPhan_a_val, ...
            TichPhan_b_val, TichPhan_N_val, TichPhan_x_val, TichPhan_y_val);
        end
            disp(TichPhan_kq)
            app.TichPhan_KetQua.Value = num2str(TichPhan_kq);    
        end

        % Callback function: NoiSuy_DaThuc, NoiSuy_DoThi, 
        % NoiSuy_KetQua, NoiSuy_Nut
        function NoiSuy_DaThucValueChanged(app, event)
        addpath(fullfile(pwd,'DoAnNoiSuy')); %Duong dan file ham chuc nang
        xa = str2num(app.NoiSuy_x.Value); % Danh sách x
        ya = str2num(app.NoiSuy_y.Value); % Danh sách y
        x = app.NoiSuy_GiaTriNoiSuy.Value; % Giá trị cần nội suy
        method = app.NoiSuy_PhuongPhap.Value; % Phương pháp được chọn
        
        switch method
            case 'Newton'
                P = newtoninterpo(xa, ya, x); % Nội suy Newton
            case 'Lagrange'
                P = lagrange(xa, ya, x); % Nội suy Lagrange
        end
        
        app.NoiSuy_KetQua.Value = sprintf('%.6f', P);
        if strcmp(method,'Newton')
            app.NoiSuy_DaThuc.Value = char(NewtonSymbolic(xa, ya));
        else
            app.NoiSuy_DaThuc.Value = char(LagrangeSymbolic(xa, ya));
        end 
        
        cla(app.NoiSuy_DoThi);
        
        xx = linspace(min(xa), max(xa), 100);
        
        if strcmp(method, 'Newton')
            yy = arrayfun(@(xi) newtoninterpo(xa, ya, xi), xx); 
        else
            yy = arrayfun(@(xi) lagrange(xa, ya, xi), xx);
        end
        
        plot(app.NoiSuy_DoThi, xx, yy, '-b', 'LineWidth', 2);       
        hold(app.NoiSuy_DoThi, 'on');
        plot(app.NoiSuy_DoThi, xa, ya, 'ro', 'MarkerSize',...
        8, 'MarkerFaceColor', 'r');
        plot(app.NoiSuy_DoThi, x, P, 'gx', 'MarkerSize',...
        10, 'LineWidth', 2);
        legend(app.NoiSuy_DoThi, 'Đa thức nội suy',...
        'Dữ liệu gốc', 'Điểm nội suy');
        grid(app.NoiSuy_DoThi, 'on');
        end

        % Button pushed function: CngvicButton
        function CngvicButtonPushed(app, event)

            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Đặng Gia Bảo';
            mssv = '22200009';
            job = 'Phương pháp nghiệm, nội suy';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
 
        end

        % Button pushed function: CngvicButton_2
        function CngvicButton_2Pushed(app, event)
            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Lê Bá Hiển';
            mssv = '22200057';
            job = 'Phương pháp tích phân';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
        end

        % Button pushed function: CngvicButton_3
        function CngvicButton_3Pushed(app, event)
            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Lê Kim Long';
            mssv = '22200098';
            job = 'Phương pháp hồi quy';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
        end

        % Button pushed function: CngvicButton_4
        function CngvicButton_4Pushed(app, event)
            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Bùi Hồng Hà';
            mssv = '22200050';
            job = 'Phương pháp đạo hàm';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
        end

        % Button pushed function: CngvicButton_5
        function CngvicButton_5Pushed(app, event)
            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Mai Thanh Lý';
            mssv = '22200100';
            job = 'Giới thiệu thành viên';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
        end

        % Button pushed function: CngvicButton_6
        function CngvicButton_6Pushed(app, event)
            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Phạm Khoa Bách';
            mssv = '22200007';
            job = 'Thiết Kế GUI';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
        end

        % Button pushed function: CngvicButton_7
        function CngvicButton_7Pushed(app, event)
            % Tạo cửa sổ mới để hiển thị thông tin
            infoFig = uifigure('Name', 'Student Info', 'NumberTitle', 'off', 'Position', [200, 200, 400, 300]);

            % Tạo các thành phần GUI để hiển thị thông tin
            uilabel(infoFig, 'Text', 'Họ và tên:', 'Position', [50, 250, 100, 20]);
            uilabel(infoFig, 'Text', 'MSSV:', 'Position', [50, 200, 100, 20]);
            uilabel(infoFig, 'Text', 'Công việc:', 'Position', [50, 150, 100, 20]);
            uilabel(infoFig, 'Text', 'Hoàn thành:', 'Position', [50, 100, 100, 20]);

            % Thông tin của sinh viên
            name = 'Nguyễn Thanh Duy';
            mssv = '22200045';
            job = 'Thiết Kế GUI';
            progress = '100%';
        
            % Hiển thị thông tin
            uilabel(infoFig, 'Text', name, 'Position', [150, 250, 200, 20]);
            uilabel(infoFig, 'Text', mssv, 'Position', [150, 200, 200, 20]);
            uilabel(infoFig, 'Text', job, 'Position', [150, 150, 200, 20]);
            uilabel(infoFig, 'Text', progress, 'Position', [150, 100, 200, 20]);
        end

        % Button pushed function: HoiQuy_Nut
        function HoiQuy_KetQuaValueChanged(app, event)
        addpath(fullfile(pwd,'DoAnHoiQuy')); %Duong dan file ham chuc nang
        x = str2num(app.HoiQuy_x.Value); % Giá trị x
        y = str2num(app.HoiQuy_y.Value); % Giá trị y 
        X = app.HoiQuy_GiaTriSuyDoan.Value; 
        method = app.HoiQuy_PhuongPhap.Value; 
        T = app.HoiQuy_TanSo.Value;
        switch method
            case 'Tuyến tính'
                 app.HoiQuy_TanSo.Visible = 'off';
                 app.HoiQuy_Label.Visible = 'off';
                 app.HoiQuy_PhuongTrinh.Value = char(TuyenTinhSymbolic(x, y));
                 a = TuyenTinhSymbolic(x, y);                   
            case 'Hàm mũ'  
                app.HoiQuy_TanSo.Visible = 'off';
                app.HoiQuy_Label.Visible = 'off';
                app.HoiQuy_PhuongTrinh.Value = char(HamMuSymbolic(x, y));
                a = HamMuSymbolic(x, y);
            case 'Logarit' 
                app.HoiQuy_TanSo.Visible = 'off';
                app.HoiQuy_Label.Visible = 'off';
                app.HoiQuy_PhuongTrinh.Value = char(LogaritSymbolic(x, y));
                a = LogaritSymbolic(x, y);
            case 'Lượng giác'
                app.HoiQuy_TanSo.Visible = 'on';
                app.HoiQuy_Label.Visible = 'on';
                app.HoiQuy_PhuongTrinh.Value = char ...
                (HoiQuyLuongGiacSymbolic(x, y, T));
                a = HoiQuyLuongGiacSymbolic(x, y, T);
        end            
        b = matlabFunction(a); 
        if strcmp(method, 'Tuyến tính')               
            yy =  HoiQuyHamTuyenTinh(x, y); 
           app.HoiQuy_KetQua.Value = num2str(b(X));                
        elseif  strcmp(method, 'Hàm mũ')
            yy =  HoiQuyHamMu(x,y); 
            app.HoiQuy_KetQua.Value = num2str(b(X));             
        elseif strcmp(method, 'Logarit')
            yy =  HoiQuyHamLogarit(x,y); 
           app.HoiQuy_KetQua.Value = num2str(b(X));
        else
            yy =  HoiQuyLuongGiac(x, y, T); 
            app.HoiQuy_KetQua.Value = num2str(b(X));
        end
        cla(app.HoiQuy_DoThi);
        plot(app.HoiQuy_DoThi, x, yy, '-b','LineWidth', 2);
        hold(app.HoiQuy_DoThi, 'on');
        plot(app.HoiQuy_DoThi, x, y, 'ro', 'MarkerSize', 8,...
        'MarkerFaceColor', 'r');
        end

        % Button pushed function: Nghiem_Nut
        function Nghiem_NhapPhuongTrinhValueChanged(app, event)
        addpath(fullfile(pwd,'DoAnTimNghiem')); % Đường dẫn file chức năng
        a = app.Nghiem_KhoangPhanLy_a.Value;
        b = app.Nghiem_KhoangPhanLy_b.Value;
        saiso = app.Nghiem_SaiSoChoPhep.Value;
        fx = str2func(['@(x) ' app.Nghiem_NhapPhuongTrinh.Value]); % Hàm fx(x)
        
        method = app.Nghiem_PhuongPhap.Value;
        switch method
            case 'Chia đôi'
                [P1, n] = chiadoi(fx, a, b, saiso);
            case 'Newton'
                [P1, n] = tieptuyen(fx, a, b, saiso);
            case 'Lặp'
                fp = str2func(['@(x) ' app.Nghiem_Nhapfp.Value]); % Hàm fp(x)
                [P1, n] = lap(fx, fp, a, b, saiso);
            case 'Dây cung'
                [P1, n] = daycung(fx, a, b, saiso);
        end
        app.Nghiem_KetQua.Value = sprintf('%.6f', P1);
        app.Nghiem_SoLanLap.Value = sprintf('%d', n);
        xx = linspace(a, b, 100);
        yy = arrayfun(fx, xx); % Tính giá trị của hàm fx tại các điểm nội suy
        if isempty(xx) || isempty(yy)
            disp('Dữ liệu đồ thị không hợp lệ!');
            return;
        end
        cla(app.Nghiem_DoThi); % Xóa đồ thị cũ
        plot(app.Nghiem_DoThi, xx, yy, '-b', 'LineWidth', 2); % Vẽ hàm fx
        hold(app.Nghiem_DoThi, 'on');
        plot(app.Nghiem_DoThi, [a, b], [fx(a), fx(b)], 'ro', ...
        'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Dữ liệu gốc
        plot(app.Nghiem_DoThi, P1, fx(P1), 'gx', 'MarkerSize', ...
        10, 'LineWidth', 2); % Hiển thị nghiệm
        legend(app.Nghiem_DoThi, 'Hàm f(x)', 'Khoảng ban đầu', 'Nghiệm');
        grid(app.Nghiem_DoThi, 'on');
        hold(app.Nghiem_DoThi, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Đồ án TH PPT - Nhóm 5';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.Nghiem_DoThi = uiaxes(app.NghiemTab);
            title(app.Nghiem_DoThi, 'Đồ thị hàm số')
            xlabel(app.Nghiem_DoThi, 'X')
            ylabel(app.Nghiem_DoThi, 'Y')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Nghiem_NhapPhuongTrinh = uieditfield(app.NghiemTab, 'text');
            app.Nghiem_NhapPhuongTrinh.Value = 'x - sin(x) - 0.25';
            app.KhongphnlyabEditFieldLabel = uilabel(app.NghiemTab);
            app.KhongphnlyabEditFieldLabel.Text = 'Khoảng phân ly [a,b]';
            app.Nghiem_KhoangPhanLy_a = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_KhoangPhanLy_a.Value = -1;
            app.Nghiem_KhoangPhanLy_b = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_KhoangPhanLy_b.Value = 2;
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.Nghiem_SaiSoChoPhep = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_SaiSoChoPhep.Value = 0.005;
            app.PhngphptmDropDownLabel = uilabel(app.NghiemTab);
            app.PhngphptmDropDownLabel.Text = 'Phương pháp tìm';
            app.Nghiem_PhuongPhap = uidropdown(app.NghiemTab);
            app.Nghiem_PhuongPhap.Items = {'Chia đôi', 'Lặp', 'Newton', 'Dây cung'};
            app.Nghiem_PhuongPhap.Value = 'Chia đôi';
            app.KtqunghimTextAreaLabel = uilabel(app.NghiemTab);
            app.KtqunghimTextAreaLabel.Text = 'Kết quả nghiệm';
            app.Nghiem_KetQua = uitextarea(app.NghiemTab);
            app.Nghiem_KetQua.Editable = 'off';
            app.SlnlpTextAreaLabel = uilabel(app.NghiemTab);
            app.SlnlpTextAreaLabel.Text = 'Số lần lặp';
            app.Nghiem_SoLanLap = uitextarea(app.NghiemTab);
            app.Nghiem_SoLanLap.Editable = 'off';
            app.NhpfpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpfpEditFieldLabel.Text = 'Nhập fp';
            app.Nghiem_Nhapfp = uieditfield(app.NghiemTab, 'text');
            app.Nghiem_Nhapfp.Value = 'sin(x) + 0.25';
            app.Nghiem_Nut = uibutton(app.NghiemTab, 'push');
            app.Nghiem_Nut.ButtonPushedFcn = createCallbackFcn(app, @Nghiem_NhapPhuongTrinhValueChanged, true);
            app.Nghiem_Nut.Text = 'Chạy chương trình';
            app.NoiSuyTab = uitab(app.TabGroup);
            app.NoiSuyTab.Title = 'Nội suy';
            app.NoiSuy_DoThi = uiaxes(app.NoiSuyTab);
            title(app.NoiSuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.NoiSuy_DoThi, 'X')
            ylabel(app.NoiSuy_DoThi, 'Y')
            app.PhngphpnisuyLabel = uilabel(app.NoiSuyTab);
            app.PhngphpnisuyLabel.Text = 'Phương pháp nội suy';
            app.NoiSuy_PhuongPhap = uidropdown(app.NoiSuyTab);
            app.NoiSuy_PhuongPhap.Items = {'Newton', 'Lagrange'};
            app.NoiSuy_PhuongPhap.Value = 'Newton';
            app.GitrnisuyLabel = uilabel(app.NoiSuyTab);
            app.GitrnisuyLabel.Text = 'Giá trị nội suy';
            app.NoiSuy_GiaTriNoiSuy = uieditfield(app.NoiSuyTab, 'numeric');
            app.NoiSuy_GiaTriNoiSuy.Value = 0.14;
            app.PhngtrnhLabel = uilabel(app.NoiSuyTab);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.NoiSuy_DaThuc = uitextarea(app.NoiSuyTab);
            app.NoiSuy_DaThuc.ValueChangedFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.NoiSuy_DaThuc.Editable = 'off';
            app.KtqunisuyTextAreaLabel = uilabel(app.NoiSuyTab);
            app.KtqunisuyTextAreaLabel.Text = 'Kết quả nội suy';
            app.NoiSuy_KetQua = uitextarea(app.NoiSuyTab);
            app.NoiSuy_KetQua.ValueChangedFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.NoiSuy_KetQua.Editable = 'off';
            app.NhpdliuxyEditFieldLabel_3 = uilabel(app.NoiSuyTab);
            app.NhpdliuxyEditFieldLabel_3.Text = 'Nhập dữ liệu x,y';
            app.NoiSuy_x = uieditfield(app.NoiSuyTab, 'text');
            app.NoiSuy_x.Value = '0.1 0.2 0.3 0.4';
            app.NoiSuy_y = uieditfield(app.NoiSuyTab, 'text');
            app.NoiSuy_y.Value = '0.09983 0.19867 0.29552 0.38942';
            app.NoiSuy_Nut = uibutton(app.NoiSuyTab, 'push');
            app.NoiSuy_Nut.ButtonPushedFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.NoiSuy_Nut.Text = 'Chạy chương trình';
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi quy';
            app.HoiQuy_DoThi = uiaxes(app.HoiQuyTab);
            title(app.HoiQuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.HoiQuy_DoThi, 'X')
            ylabel(app.HoiQuy_DoThi, 'Y')
            app.GitrcnsuyonLabel = uilabel(app.HoiQuyTab);
            app.GitrcnsuyonLabel.Text = 'Giá trị cần suy đoán';
            app.HoiQuy_GiaTriSuyDoan = uieditfield(app.HoiQuyTab, 'numeric');
            app.HoiQuy_GiaTriSuyDoan.Value = 9;
            app.PhngphphiquyLabel = uilabel(app.HoiQuyTab);
            app.PhngphphiquyLabel.Text = 'Phương pháp hồi quy';
            app.HoiQuy_PhuongPhap = uidropdown(app.HoiQuyTab);
            app.HoiQuy_PhuongPhap.Items = {'Tuyến tính', 'Lượng giác', 'Hàm mũ', 'Logarit'};
            app.HoiQuy_PhuongPhap.Value = 'Tuyến tính';
            app.PhngtrnhLabel_2 = uilabel(app.HoiQuyTab);
            app.PhngtrnhLabel_2.Text = 'Phương trình';
            app.HoiQuy_PhuongTrinh = uitextarea(app.HoiQuyTab);
            app.HoiQuy_PhuongTrinh.Editable = 'off';
            app.KtqudonLabel = uilabel(app.HoiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.HoiQuy_KetQua = uitextarea(app.HoiQuyTab);
            app.HoiQuy_KetQua.Editable = 'off';
            app.NhpxyEditFieldLabel = uilabel(app.HoiQuyTab);
            app.NhpxyEditFieldLabel.Text = 'Nhập x,y';
            app.HoiQuy_x = uieditfield(app.HoiQuyTab, 'text');
            app.HoiQuy_x.Value = '2.5 3.5 5 6 7.5 10 12.5 15 17.5 20';
            app.Label_11 = uilabel(app.HoiQuyTab);
            app.Label_11.Text = '';
            app.HoiQuy_y = uieditfield(app.HoiQuyTab, 'text');
            app.HoiQuy_y.Value = '13 11 8.5 8.2 7 6.2 5.2 4.8 4.6 4.3';
            app.HoiQuy_Nut = uibutton(app.HoiQuyTab, 'push');
            app.HoiQuy_Nut.ButtonPushedFcn = createCallbackFcn(app, @HoiQuy_KetQuaValueChanged, true);
            app.HoiQuy_Nut.Text = 'Chạy chương trình';
            app.HoiQuy_Label = uilabel(app.HoiQuyTab);
            app.HoiQuy_Label.Visible = 'off';
            app.HoiQuy_Label.Text = 'Tần số';
            app.HoiQuy_TanSo = uieditfield(app.HoiQuyTab, 'numeric');
            app.HoiQuy_TanSo.Visible = 'off';
            app.HoiQuy_TanSo.Value = 1;
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo hàm';
            app.NhphmsLabel = uilabel(app.DaoHamTab);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.DaoHam_HamSo = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_HamSo.Value = '1/sqrt(1-x^2)';
            app.GitrcnohmLabel = uilabel(app.DaoHamTab);
            app.GitrcnohmLabel.Text = 'Giá trị cần đạo hàm';
            app.DaoHam_GiaTri = uieditfield(app.DaoHamTab, 'numeric');
            app.DaoHam_GiaTri.Value = 0.5;
            app.GitrsaisSwitchLabel = uilabel(app.DaoHamTab);
            app.GitrsaisSwitchLabel.Text = 'Giá trị sai số';
            app.DaoHam_GiaTriSaiSo = uiswitch(app.DaoHamTab, 'slider');
            app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
            app.DaoHam_GiaTriSaiSo.Value = 'O(h)';
            app.PhngphpohmLabel = uilabel(app.DaoHamTab);
            app.PhngphpohmLabel.Text = 'Phương pháp đạo hàm';
            app.DaoHam_PhuongPhap = uidropdown(app.DaoHamTab);
            app.DaoHam_PhuongPhap.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DaoHam_PhuongPhap.Value = 'Xấp xỉ tiến';
            app.KtquTextAreaLabel = uilabel(app.DaoHamTab);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.DaoHam_KetQua = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQua.Editable = 'off';
            app.KtquchnhxcLabel = uilabel(app.DaoHamTab);
            app.KtquchnhxcLabel.Text = 'Kết quả chính xác';
            app.DaoHam_KetQuaChinhXac = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQuaChinhXac.Editable = 'off';
            app.NhpdliuxyEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpdliuxyEditFieldLabel.Text = 'Nhập dữ liệu x, y';
            app.DaoHam_x = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_x.Value = '0.1 0.3 0.5 0.7 0.9';
            app.DaoHam_y = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_y.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.NoiSuy_Nut_2 = uibutton(app.DaoHamTab, 'push');
            app.NoiSuy_Nut_2.ButtonPushedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.NoiSuy_Nut_2.Text = 'Chạy chương trình';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích phân';
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TichPhan_N = uitextarea(app.TichPhanTab);
            app.TichPhan_N.Value = {'10'};
            app.PhngphptchphnLabel = uilabel(app.TichPhanTab);
            app.PhngphptchphnLabel.Text = 'Phương pháp tích phân';
            app.TichPhan_PhuongPhap = uidropdown(app.TichPhanTab);
            app.TichPhan_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TichPhan_PhuongPhap.Value = 'Hình thang';
            app.KtquTextAreaLabel_2 = uilabel(app.TichPhanTab);
            app.KtquTextAreaLabel_2.Text = 'Kết quả';
            app.TichPhan_KetQua = uitextarea(app.TichPhanTab);
            app.TichPhan_KetQua.Editable = 'off';
            app.NhpdliuxyEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.NhpdliuxyEditFieldLabel_2.Text = 'Nhập  dữ liệu x, y';
            app.TichPhan_x = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_y = uieditfield(app.TichPhanTab, 'text');
            app.NhpcnabLabel = uilabel(app.TichPhanTab);
            app.NhpcnabLabel.Text = 'Nhập cận a, b';
            app.TichPhan_a = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_a.Value = '0';
            app.TichPhan_b = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_b.Value = '1';
            app.NhphmsLabel_2 = uilabel(app.TichPhanTab);
            app.NhphmsLabel_2.Text = 'Nhập hàm số';
            app.TichPhan_HamSo = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_HamSo.Value = 'x^3*sin(x)';
            app.TichPhan_NutNhan = uibutton(app.TichPhanTab, 'push');
            app.TichPhan_NutNhan.ButtonPushedFcn = createCallbackFcn(app, @TichPhan_NValueChanged2, true);
            app.TichPhan_NutNhan.Text = 'Chạy chương trình';
            app.GioiThieuTab = uitab(app.TabGroup);
            app.GioiThieuTab.Title = 'Giới thiệu nhóm';
            app.Label = uilabel(app.GioiThieuTab);
            app.Label.Text = '';
            app.EditField = uieditfield(app.GioiThieuTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Thành Viên Nhóm 5';
            app.Label_3 = uilabel(app.GioiThieuTab);
            app.Label_3.Text = '2';
            app.EditField_3 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'Lê Bá Hiển';
            app.Label_5 = uilabel(app.GioiThieuTab);
            app.Label_5.Text = '3';
            app.EditField_5 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Lê Kim Long';
            app.Label_6 = uilabel(app.GioiThieuTab);
            app.Label_6.Text = '4';
            app.EditField_6 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Bùi Hồng Hà';
            app.Label_7 = uilabel(app.GioiThieuTab);
            app.Label_7.Text = '5';
            app.EditField_7 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Mai Thanh Lý';
            app.Label_8 = uilabel(app.GioiThieuTab);
            app.Label_8.Text = '6';
            app.EditField_8 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'Phạm Khoa Bách';
            app.Label_9 = uilabel(app.GioiThieuTab);
            app.Label_9.Text = '7';
            app.EditField_9 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Value = 'Nguyễn Thanh Duy';
            app.Label_10 = uilabel(app.GioiThieuTab);
            app.Label_10.Text = '1';
            app.EditField_10 = uieditfield(app.GioiThieuTab, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Value = 'Đặng Gia Bảo';
            app.CngvicButton = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton.ButtonPushedFcn = createCallbackFcn(app, @CngvicButtonPushed, true);
            app.CngvicButton.Text = 'Công việc';
            app.CngvicButton_2 = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton_2.ButtonPushedFcn = createCallbackFcn(app, @CngvicButton_2Pushed, true);
            app.CngvicButton_2.Text = 'Công việc';
            app.CngvicButton_3 = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton_3.ButtonPushedFcn = createCallbackFcn(app, @CngvicButton_3Pushed, true);
            app.CngvicButton_3.Text = 'Công việc';
            app.CngvicButton_4 = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton_4.ButtonPushedFcn = createCallbackFcn(app, @CngvicButton_4Pushed, true);
            app.CngvicButton_4.Text = 'Công việc';
            app.CngvicButton_5 = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton_5.ButtonPushedFcn = createCallbackFcn(app, @CngvicButton_5Pushed, true);
            app.CngvicButton_5.Text = 'Công việc';
            app.CngvicButton_6 = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton_6.ButtonPushedFcn = createCallbackFcn(app, @CngvicButton_6Pushed, true);
            app.CngvicButton_6.Text = 'Công việc';
            app.CngvicButton_7 = uibutton(app.GioiThieuTab, 'push');
            app.CngvicButton_7.ButtonPushedFcn = createCallbackFcn(app, @CngvicButton_7Pushed, true);
            app.CngvicButton_7.Text = 'Công việc';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DoAnMatlab

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end