classdef DoAnMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nUIFigure                   matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        NghiemTab                   matlab.ui.container.Tab
        Nghiem_Nhapfp               matlab.ui.control.EditField
        NhpfpEditFieldLabel         matlab.ui.control.Label
        Nghiem_SoLanLap             matlab.ui.control.TextArea
        SlnlpTextAreaLabel          matlab.ui.control.Label
        Nghiem_KetQua               matlab.ui.control.TextArea
        KtqunghimTextAreaLabel      matlab.ui.control.Label
        Nghiem_PhuongPhap           matlab.ui.control.DropDown
        PhngphptmDropDownLabel      matlab.ui.control.Label
        Nghiem_SaiSoChoPhep         matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel    matlab.ui.control.Label
        Nghiem_KhoangPhanLy_b       matlab.ui.control.NumericEditField
        Nghiem_KhoangPhanLy_a       matlab.ui.control.NumericEditField
        KhongphnlyabEditFieldLabel  matlab.ui.control.Label
        Nghiem_NhapPhuongTrinh      matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel   matlab.ui.control.Label
        Nghiem_DoThi                matlab.ui.control.UIAxes
        NoiSuyTab                   matlab.ui.container.Tab
        NoiSuy_KetQua               matlab.ui.control.TextArea
        KtqunisuyTextAreaLabel      matlab.ui.control.Label
        NoiSuy_DaThuc               matlab.ui.control.TextArea
        PhngtrnhLabel               matlab.ui.control.Label
        NoiSuy_GiaTriNoiSuy         matlab.ui.control.NumericEditField
        GitrnisuyLabel              matlab.ui.control.Label
        NoiSuy_y                    matlab.ui.control.NumericEditField
        NoiSuy_PhuongPhap           matlab.ui.control.DropDown
        PhngphpnisuyLabel           matlab.ui.control.Label
        NoiSuy_x                    matlab.ui.control.NumericEditField
        NhpdliuxyLabel              matlab.ui.control.Label
        NoiSuy_DoThi                matlab.ui.control.UIAxes
        HoiQuyTab                   matlab.ui.container.Tab
        HoiQuy_KetQua               matlab.ui.control.TextArea
        KtqudonLabel                matlab.ui.control.Label
        HoiQuy_PhuongTrinh          matlab.ui.control.TextArea
        PhngtrnhLabel_2             matlab.ui.control.Label
        HoiQuy_PhuongPhap           matlab.ui.control.DropDown
        PhngphpnisuyLabel_2         matlab.ui.control.Label
        HoiQuy_GiaTriSuyDoan        matlab.ui.control.NumericEditField
        GitrcnsuyonLabel            matlab.ui.control.Label
        HoiQuy_x                    matlab.ui.control.NumericEditField
        NhpdliuxyLabel_2            matlab.ui.control.Label
        HoiQuy_y                    matlab.ui.control.NumericEditField
        HoiQuy_DoThi                matlab.ui.control.UIAxes
        DaoHamTab                   matlab.ui.container.Tab
        DaoHam_y                    matlab.ui.control.EditField
        DaoHam_x                    matlab.ui.control.EditField
        NhpdliuxvyEditFieldLabel    matlab.ui.control.Label
        DaoHam_KetQua               matlab.ui.control.TextArea
        KtquTextAreaLabel           matlab.ui.control.Label
        DaoHam_PhuongPhap           matlab.ui.control.DropDown
        PhngphpnisuyLabel_3         matlab.ui.control.Label
        DaoHam_GiaTriSaiSo          matlab.ui.control.Switch
        GitrsaisSwitchLabel         matlab.ui.control.Label
        DaoHam_GiaTri               matlab.ui.control.NumericEditField
        GitrcnohmLabel              matlab.ui.control.Label
        DaoHam_BuocH                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel        matlab.ui.control.Label
        DaoHam_HamSo                matlab.ui.control.EditField
        NhphmsLabel                 matlab.ui.control.Label
        TichPhanTab                 matlab.ui.container.Tab
        TichPhan_KetQua             matlab.ui.control.TextArea
        KtquTextAreaLabel_2         matlab.ui.control.Label
        TIchPhan_PhuongPhap         matlab.ui.control.DropDown
        PhngphpnisuyLabel_4         matlab.ui.control.Label
        TichPhan_N                  matlab.ui.control.TextArea
        NhpNLabel                   matlab.ui.control.Label
        TichPhan_Can                matlab.ui.control.NumericEditField
        NhpcnabLabel                matlab.ui.control.Label
        TichPhan_HamSo              matlab.ui.control.EditField
        NhphmsLabel_2               matlab.ui.control.Label
        TichPhan_y                  matlab.ui.control.NumericEditField
        TichPhan_x                  matlab.ui.control.NumericEditField
        NhpdliuxyLabel_4            matlab.ui.control.Label
        GioiThieuTab                matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: DaoHam_KetQua
        function DaoHam_KetQuaValueChanging(app, event)
            syms x;%Khai báo x theo kiểu symbol
            x1 = app.DaoHam_x.Value;%Hàm lấy giá trị x1
            y1 = app.DaoHam_y.Value;%Hàm lấy giá trị y1
            x1=str2num(x1);
            y1=str2num(y1);
            h = app.DaoHam_BuocH.Value;%Hàm lấy giá trị bước nhảy h
            x0 = app.DaoHam_GiaTri.Value;%Hàm lấy giá trị xo
            Oh = app.DaoHam_GiaTriSaiSo.Value;%Hàm lấy giá trị Oh
            fx = app.DaoHam_HamSo.Value;%Lấy giá trị hàm fx
            pp = app.DaoHam_PhuongPhap.Value;%Hàm lấy giá trị pp
            if strcmp(pp,'Xấp xỉ tiến')%Nếu giá trị của pp = 'Xấp xỉ tiến'
                fd1 = xapxitien(fx,x0,x1,y1,h,Oh);%Thực hiện hàm con function xapxitien
            else
                if strcmp(pp,'Xấp xỉ lùi')%Nếu giá trị của pp = 'Xấp xỉ lùi'
                        fd1 = xapxilui(fx,x0,x1,y1,h,Oh);%Thực hiện hàm con function xapxilui
                    else %Nếu giá trị của pp không phải là 'Xấp xỉ tiến', 'Xấp xỉ lùi'
                        fd1 = xapxitrungtam(fx,x0,x1,y1,h,Oh);%Thực hiện hàm con function xapxitrungtam 
                end
            end
            app.DaoHam_KetQua.Value=num2str(fd1);%Xuất giá trị kiểu string sang edit test
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nUIFigure = uifigure('Visible', 'off');
            app.nUIFigure.Name = 'Đồ án';
            app.TabGroup = uitabgroup(app.nUIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.Nghiem_DoThi = uiaxes(app.NghiemTab);
            title(app.Nghiem_DoThi, 'Đồ thị hàm số')
            xlabel(app.Nghiem_DoThi, 'X')
            ylabel(app.Nghiem_DoThi, 'Y')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Nghiem_NhapPhuongTrinh = uieditfield(app.NghiemTab, 'text');
            app.KhongphnlyabEditFieldLabel = uilabel(app.NghiemTab);
            app.KhongphnlyabEditFieldLabel.Text = 'Khoảng phân ly [a,b]';
            app.Nghiem_KhoangPhanLy_a = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_KhoangPhanLy_b = uieditfield(app.NghiemTab, 'numeric');
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.Nghiem_SaiSoChoPhep = uieditfield(app.NghiemTab, 'numeric');
            app.PhngphptmDropDownLabel = uilabel(app.NghiemTab);
            app.PhngphptmDropDownLabel.Text = 'Phương pháp tìm';
            app.Nghiem_PhuongPhap = uidropdown(app.NghiemTab);
            app.Nghiem_PhuongPhap.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.Nghiem_PhuongPhap.Value = 'Chia đôi';
            app.KtqunghimTextAreaLabel = uilabel(app.NghiemTab);
            app.KtqunghimTextAreaLabel.Text = 'Kết quả nghiệm';
            app.Nghiem_KetQua = uitextarea(app.NghiemTab);
            app.Nghiem_KetQua.Editable = 'off';
            app.SlnlpTextAreaLabel = uilabel(app.NghiemTab);
            app.SlnlpTextAreaLabel.Text = 'Số lần lặp';
            app.Nghiem_SoLanLap = uitextarea(app.NghiemTab);
            app.Nghiem_SoLanLap.Editable = 'off';
            app.NhpfpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpfpEditFieldLabel.Text = 'Nhập fp';
            app.Nghiem_Nhapfp = uieditfield(app.NghiemTab, 'text');
            app.NoiSuyTab = uitab(app.TabGroup);
            app.NoiSuyTab.Title = 'Nội suy';
            app.NoiSuy_DoThi = uiaxes(app.NoiSuyTab);
            title(app.NoiSuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.NoiSuy_DoThi, 'X')
            ylabel(app.NoiSuy_DoThi, 'Y')
            app.NhpdliuxyLabel = uilabel(app.NoiSuyTab);
            app.NhpdliuxyLabel.Text = 'Nhập dữ liệu x, y';
            app.NoiSuy_x = uieditfield(app.NoiSuyTab, 'numeric');
            app.PhngphpnisuyLabel = uilabel(app.NoiSuyTab);
            app.PhngphpnisuyLabel.Text = 'Phương pháp nội suy';
            app.NoiSuy_PhuongPhap = uidropdown(app.NoiSuyTab);
            app.NoiSuy_PhuongPhap.Items = {'Newton', 'Lagrange'};
            app.NoiSuy_PhuongPhap.Value = 'Newton';
            app.NoiSuy_y = uieditfield(app.NoiSuyTab, 'numeric');
            app.GitrnisuyLabel = uilabel(app.NoiSuyTab);
            app.GitrnisuyLabel.Text = 'Giá trị nội suy';
            app.NoiSuy_GiaTriNoiSuy = uieditfield(app.NoiSuyTab, 'numeric');
            app.PhngtrnhLabel = uilabel(app.NoiSuyTab);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.NoiSuy_DaThuc = uitextarea(app.NoiSuyTab);
            app.KtqunisuyTextAreaLabel = uilabel(app.NoiSuyTab);
            app.KtqunisuyTextAreaLabel.Text = 'Kết quả nội suy';
            app.NoiSuy_KetQua = uitextarea(app.NoiSuyTab);
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi quy';
            app.HoiQuy_DoThi = uiaxes(app.HoiQuyTab);
            title(app.HoiQuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.HoiQuy_DoThi, 'X')
            ylabel(app.HoiQuy_DoThi, 'Y')
            app.HoiQuy_y = uieditfield(app.HoiQuyTab, 'numeric');
            app.NhpdliuxyLabel_2 = uilabel(app.HoiQuyTab);
            app.NhpdliuxyLabel_2.Text = 'Nhập dữ liệu x, y';
            app.HoiQuy_x = uieditfield(app.HoiQuyTab, 'numeric');
            app.GitrcnsuyonLabel = uilabel(app.HoiQuyTab);
            app.GitrcnsuyonLabel.Text = 'Giá trị cần suy đoán';
            app.HoiQuy_GiaTriSuyDoan = uieditfield(app.HoiQuyTab, 'numeric');
            app.PhngphpnisuyLabel_2 = uilabel(app.HoiQuyTab);
            app.PhngphpnisuyLabel_2.Text = 'Phương pháp nội suy';
            app.HoiQuy_PhuongPhap = uidropdown(app.HoiQuyTab);
            app.HoiQuy_PhuongPhap.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.HoiQuy_PhuongPhap.Value = 'Tuyến tính';
            app.PhngtrnhLabel_2 = uilabel(app.HoiQuyTab);
            app.PhngtrnhLabel_2.Text = 'Phương trình';
            app.HoiQuy_PhuongTrinh = uitextarea(app.HoiQuyTab);
            app.KtqudonLabel = uilabel(app.HoiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.HoiQuy_KetQua = uitextarea(app.HoiQuyTab);
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo hàm';
            app.NhphmsLabel = uilabel(app.DaoHamTab);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.DaoHam_HamSo = uieditfield(app.DaoHamTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.DaoHam_BuocH = uieditfield(app.DaoHamTab, 'numeric');
            app.GitrcnohmLabel = uilabel(app.DaoHamTab);
            app.GitrcnohmLabel.Text = 'Giá trị cần đạo hàm';
            app.DaoHam_GiaTri = uieditfield(app.DaoHamTab, 'numeric');
            app.GitrsaisSwitchLabel = uilabel(app.DaoHamTab);
            app.GitrsaisSwitchLabel.Text = 'Giá trị sai số';
            app.DaoHam_GiaTriSaiSo = uiswitch(app.DaoHamTab, 'slider');
            app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
            app.DaoHam_GiaTriSaiSo.Value = 'O(h)';
            app.PhngphpnisuyLabel_3 = uilabel(app.DaoHamTab);
            app.PhngphpnisuyLabel_3.Text = 'Phương pháp đạo hàm';
            app.DaoHam_PhuongPhap = uidropdown(app.DaoHamTab);
            app.DaoHam_PhuongPhap.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DaoHam_PhuongPhap.Value = 'Xấp xỉ tiến';
            app.KtquTextAreaLabel = uilabel(app.DaoHamTab);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.DaoHam_KetQua = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQua.ValueChangingFcn = createCallbackFcn(app, @DaoHam_KetQuaValueChanging, true);
            app.NhpdliuxvyEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpdliuxvyEditFieldLabel.Text = 'Nhập dữ liệu x và y';
            app.DaoHam_x = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_y = uieditfield(app.DaoHamTab, 'text');
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích phân';
            app.NhpdliuxyLabel_4 = uilabel(app.TichPhanTab);
            app.NhpdliuxyLabel_4.Text = 'Nhập dữ liệu x, y';
            app.TichPhan_x = uieditfield(app.TichPhanTab, 'numeric');
            app.TichPhan_y = uieditfield(app.TichPhanTab, 'numeric');
            app.NhphmsLabel_2 = uilabel(app.TichPhanTab);
            app.NhphmsLabel_2.Text = 'Nhập hàm số';
            app.TichPhan_HamSo = uieditfield(app.TichPhanTab, 'text');
            app.NhpcnabLabel = uilabel(app.TichPhanTab);
            app.NhpcnabLabel.Text = 'Nhập cận [a,b]';
            app.TichPhan_Can = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TichPhan_N = uitextarea(app.TichPhanTab);
            app.PhngphpnisuyLabel_4 = uilabel(app.TichPhanTab);
            app.PhngphpnisuyLabel_4.Text = 'Phương pháp nội suy';
            app.TIchPhan_PhuongPhap = uidropdown(app.TichPhanTab);
            app.TIchPhan_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TIchPhan_PhuongPhap.Value = 'Hình thang';
            app.KtquTextAreaLabel_2 = uilabel(app.TichPhanTab);
            app.KtquTextAreaLabel_2.Text = 'Kết quả';
            app.TichPhan_KetQua = uitextarea(app.TichPhanTab);
            app.GioiThieuTab = uitab(app.TabGroup);
            app.GioiThieuTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.nUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nUIFigure)
        end
    end
end